classdef SudokuMacOs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SudokuUIFigure          matlab.ui.Figure
        TextArea_81             matlab.ui.control.TextArea
        TextArea_80             matlab.ui.control.TextArea
        TextArea_79             matlab.ui.control.TextArea
        TextArea_78             matlab.ui.control.TextArea
        TextArea_77             matlab.ui.control.TextArea
        TextArea_76             matlab.ui.control.TextArea
        TextArea_75             matlab.ui.control.TextArea
        TextArea_74             matlab.ui.control.TextArea
        TextArea_73             matlab.ui.control.TextArea
        TextArea_72             matlab.ui.control.TextArea
        TextArea_71             matlab.ui.control.TextArea
        TextArea_70             matlab.ui.control.TextArea
        TextArea_69             matlab.ui.control.TextArea
        TextArea_68             matlab.ui.control.TextArea
        TextArea_67             matlab.ui.control.TextArea
        TextArea_66             matlab.ui.control.TextArea
        TextArea_65             matlab.ui.control.TextArea
        TextArea_64             matlab.ui.control.TextArea
        TextArea_63             matlab.ui.control.TextArea
        TextArea_62             matlab.ui.control.TextArea
        TextArea_61             matlab.ui.control.TextArea
        TextArea_60             matlab.ui.control.TextArea
        TextArea_59             matlab.ui.control.TextArea
        TextArea_58             matlab.ui.control.TextArea
        TextArea_57             matlab.ui.control.TextArea
        TextArea_56             matlab.ui.control.TextArea
        TextArea_55             matlab.ui.control.TextArea
        TextArea_54             matlab.ui.control.TextArea
        TextArea_53             matlab.ui.control.TextArea
        TextArea_52             matlab.ui.control.TextArea
        TextArea_51             matlab.ui.control.TextArea
        TextArea_50             matlab.ui.control.TextArea
        TextArea_49             matlab.ui.control.TextArea
        TextArea_48             matlab.ui.control.TextArea
        TextArea_47             matlab.ui.control.TextArea
        TextArea_46             matlab.ui.control.TextArea
        TextArea_45             matlab.ui.control.TextArea
        TextArea_44             matlab.ui.control.TextArea
        TextArea_43             matlab.ui.control.TextArea
        TextArea_42             matlab.ui.control.TextArea
        TextArea_41             matlab.ui.control.TextArea
        TextArea_40             matlab.ui.control.TextArea
        TextArea_39             matlab.ui.control.TextArea
        TextArea_38             matlab.ui.control.TextArea
        TextArea_37             matlab.ui.control.TextArea
        TextArea_36             matlab.ui.control.TextArea
        TextArea_35             matlab.ui.control.TextArea
        TextArea_34             matlab.ui.control.TextArea
        TextArea_33             matlab.ui.control.TextArea
        TextArea_32             matlab.ui.control.TextArea
        TextArea_31             matlab.ui.control.TextArea
        TextArea_30             matlab.ui.control.TextArea
        TextArea_29             matlab.ui.control.TextArea
        TextArea_28             matlab.ui.control.TextArea
        TextArea_27             matlab.ui.control.TextArea
        TextArea_26             matlab.ui.control.TextArea
        TextArea_25             matlab.ui.control.TextArea
        TextArea_24             matlab.ui.control.TextArea
        TextArea_23             matlab.ui.control.TextArea
        TextArea_22             matlab.ui.control.TextArea
        TextArea_21             matlab.ui.control.TextArea
        TextArea_20             matlab.ui.control.TextArea
        TextArea_19             matlab.ui.control.TextArea
        TextArea_18             matlab.ui.control.TextArea
        TextArea_17             matlab.ui.control.TextArea
        TextArea_16             matlab.ui.control.TextArea
        TextArea_15             matlab.ui.control.TextArea
        TextArea_14             matlab.ui.control.TextArea
        TextArea_13             matlab.ui.control.TextArea
        TextArea_12             matlab.ui.control.TextArea
        TextArea_11             matlab.ui.control.TextArea
        TextArea_10             matlab.ui.control.TextArea
        TextArea_9              matlab.ui.control.TextArea
        TextArea_8              matlab.ui.control.TextArea
        TextArea_7              matlab.ui.control.TextArea
        TextArea_6              matlab.ui.control.TextArea
        TextArea_5              matlab.ui.control.TextArea
        TextArea_4              matlab.ui.control.TextArea
        TextArea_3              matlab.ui.control.TextArea
        TextArea_2              matlab.ui.control.TextArea
        TextArea_1              matlab.ui.control.TextArea
        SUDOKULabel             matlab.ui.control.Label
        EscanearsudokuButton    matlab.ui.control.Button
        ReiniciarButton         matlab.ui.control.Button
        EntradaporimagenButton  matlab.ui.control.Button
        EntradaporvozButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        original_sudoku = zeros(9); % Input sudoku
        current_sudoku = zeros(9); % Sudoku with user changes
        recObj = audiorecorder(8000, 16, 1) % Audio recorder
        
        vid = videoinput('macvideo');
        isCamActive = 0;
        
    end
    
    methods (Access = private)
        function taken = originallyTaken(app, row, col)
            taken =  app.original_sudoku(row, col) ~= 0;         
        end

        function taken = alreadyTaken(app, row, col)
            taken =  app.current_sudoku(row, col) ~= 0;         
        end

        function res = valid(app, row, col, num)
            val = find(app.current_sudoku(:, col) == num, 1); % Same column
            if ~isempty(val)
                res = 0;
                return
            end

            val = find(app.current_sudoku(row, :) == num, 1); % Same row
            if ~isempty(val)
                res = 0;
                return
            end

            sqRow = ceil(row/3) - 1; % Same square
            sqCol = ceil(col/3) - 1;
            val = find(app.current_sudoku(3*sqRow+1:3*(sqRow+1), ...
                3*sqCol+1:3*(sqCol+1)) == num, 1);
            if ~isempty(val)
                res = 0;
                return
            end
            res = 1;   
        end
       
        % Insert number and related dialogs
        function insertarNumero(app, num, row, col)
            if num == 0 % Delete
                %Dialog
                msg = strcat('¿Eliminar número de fila', 32, ...
                    num2str(row) , 32, 'columna', 32, num2str(col), '?');

                title = 'Confirmar acción';
                selection = uiconfirm(app.SudokuUIFigure,msg,title, ...
                    'Options',{'Confirmar','Cancelar'});

                if strcmp(selection, 'Confirmar')
                    ind = sub2ind([9 9], col, row);
                    if app.originallyTaken(row, col) % in original sudoku
                        uialert(app.SudokuUIFigure,'No se puede eliminar este número.','Error');
                    else % Delete number
                        app.current_sudoku(row, col) = num;
                        eval("app.TextArea_" + num2str(ind) + ".Value = ''");
                    end
                end
            else % Insert
                % Dialog
                msg = strcat('¿Insertar', 32, num2str(num), 32, ...
                    'en fila', 32, num2str(row) , 32, 'columna', 32, ...
                    num2str(col), '?');

                title = 'Confirmar acción';
                selection = uiconfirm(app.SudokuUIFigure,msg,title, ...
                    'Options',{'Confirmar','Cancelar'});

                if strcmp(selection, 'Confirmar')
                    ind = sub2ind([9 9], col, row);
                    if app.alreadyTaken(row, col) % no number in that position
                        uialert(app.SudokuUIFigure,'Posición no disponible.','Error');
                    elseif ~app.valid(row, col, num) % conflicts
                        uialert(app.SudokuUIFigure,'No se puede escribir el número en la posición' ,'Error');
                    else % no conflicts -> add number
                        app.current_sudoku(row, col) = num;
                        eval("app.TextArea_" + num2str(ind) + ".Value = '" + num2str(num) + "'");
                    end
                end

                z = find(app.current_sudoku == 0, 1);
                if isempty(z) % User won
                    uialert(app.SudokuUIFigure,...
                        '¡Enhorabuena, has compleatado el sudoku!', ...
                        'Sudoku completado', 'Icon', 'success');
                end
            end

        end

        function stopScanSudoku(app)
            % End recording
            app.isCamActive = 0;
            closepreview;
            close all;
            stop(app.vid);

            % Edit button
            app.EscanearsudokuButton.BackgroundColor = [0.502 0.702 0.902];
            app.EscanearsudokuButton.Text = 'Escanear sudoku';
            app.EntradaporvozButton.Enable = 'on';
            app.ReiniciarButton.Enable = 'on';
            app.EntradaporimagenButton.Enable = 'on';
        end

        function stopVideoInput(app)
            % End recording
            app.isCamActive = 0;
            closepreview;
            close all;
            stop(app.vid);

            % Edit button
            app.EntradaporimagenButton.BackgroundColor = [0.96 0.96 0.96];
            app.EntradaporimagenButton.Text = 'Entrada por imagen';
            app.EntradaporvozButton.Enable = 'on';
            app.ReiniciarButton.Enable = 'on';
            app.EscanearsudokuButton.Enable = 'on';
        end

        function [valid] = sudoku_check(app, sudoku)
        %SUDOKU_CHECK Summary of this function goes here
        %   Detailed explanation goes here
            valid = 1;
            for i = 1:9
                for j = 1:9
                    if sudoku(i,j) ~= 0
                        if sum(sudoku(i,:) == sudoku(i,j)) > 1
                            valid = 0;
                            return
                        end
                        if sum(sudoku(:,j) == sudoku(i,j)) > 1
                            valid = 0;
                            return
                        end
                        if sum(sudoku(3*floor((i-1)/3)+1:3*floor((i-1)/3)+3,3*floor((j-1)/3)+1:3*floor((j-1)/3)+3) == sudoku(i,j)) > 1
                            valid = 0;
                            return
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            app.current_sudoku = app.original_sudoku;
            for i=1:81
                if eval("strcmp(app.TextArea_" + num2str(i) + ".FontWeight, 'normal')")
                    eval("app.TextArea_" + num2str(i) + ".Value = ''");
                end
            end
        end

        % Button pushed function: EntradaporvozButton
        function EntradaporvozButtonPushed(app, event)
            if app.EntradaporvozButton.BackgroundColor == [0.96 0.96 0.96]
                % Edit button
                app.EntradaporvozButton.BackgroundColor = [0.76 0.16 0.16];
                app.EntradaporvozButton.Text = 'Parar';
                app.EntradaporimagenButton.Enable = 'off';
                app.ReiniciarButton.Enable = 'off';
                app.EscanearsudokuButton.Enable = 'off';

                % Start recording
                record(app.recObj);
            else
                % Edit button
                app.EntradaporvozButton.BackgroundColor = [0.96 0.96 0.96];
                app.EntradaporvozButton.Text = 'Entrada por voz';
                app.EntradaporimagenButton.Enable = 'on';
                app.ReiniciarButton.Enable = 'on';
                app.EscanearsudokuButton.Enable = 'on';

                % End recording
                stop(app.recObj);
                signal = getaudiodata(app.recObj);

                [row, col, num] = voiceInput(signal);

                if row == 0 || col == 0
                    uialert(app.SudokuUIFigure,'No se ha podido procesar el comando por voz','Error');
                else
                    app.insertarNumero(num, row, col);
                end
                
            end
            
        end

        % Button pushed function: EntradaporimagenButton
        function EntradaporimagenButtonPushed(app, event)
            if app.EntradaporimagenButton.BackgroundColor == [0.96 0.96 0.96]
                % Edit button
                app.EntradaporimagenButton.BackgroundColor = [0.76 0.16 0.16];
                app.EntradaporimagenButton.Text = 'Parar';
                app.EntradaporvozButton.Enable = 'off';
                app.ReiniciarButton.Enable = 'off';
                app.EscanearsudokuButton.Enable = 'off';

                % Start recording
                set(app.vid, 'FramesPerTrigger', 200);
                set(app.vid, 'ReturnedColorSpace', 'rgb');
                set(app.vid,'frameGrabInterval', 5);
                flushdata(app.vid);
                start(app.vid);

                row = 0;
                app.isCamActive = 1;
                
                figure('Units', 'pixels', 'Position', [100 100 340 260]);
                axes('Units', 'pixels', 'Position', [10 10 320 240]);
                vidRes = get(app.vid, 'VideoResolution');
                nBands = get(app.vid, 'NumberOfBands');
                hImage = image(zeros(vidRes(2), vidRes(1), nBands));

                preview(app.vid, hImage);

                while ((row == 0 || col == 0) && app.isCamActive == 1)
                    data = getsnapshot(app.vid);
                    [row, col, num] = imageInput(data);
                    pause(1)
                end
                flushdata(app.vid);
                if (row == 0 || col == 0)
                    if app.isCamActive
                        uialert(app.SudokuUIFigure,'No se ha podido procesar el comando por imagen','Error');
                    end
                else
                    app.insertarNumero(num, row, col);
                end
                app.stopVideoInput;
            else
                app.stopVideoInput;
            end
        end

        % Button pushed function: EscanearsudokuButton
        function EscanearsudokuButtonPushed(app, event)
            if app.EscanearsudokuButton.BackgroundColor == [0.502 0.702 0.902]
                % Edit button
                app.EscanearsudokuButton.BackgroundColor = [0.76 0.16 0.16];
                app.EscanearsudokuButton.Text = 'Parar';
                app.EntradaporvozButton.Enable = 'off';
                app.ReiniciarButton.Enable = 'off';
                app.EntradaporimagenButton.Enable = 'off';


                % Start recording
                set(app.vid, 'FramesPerTrigger', 200);
                set(app.vid, 'ReturnedColorSpace', 'rgb');
                set(app.vid,'frameGrabInterval', 3);
                flushdata(app.vid);
                start(app.vid);

                sudoku = 0;
                app.isCamActive = 1;
                
                figure('Units', 'pixels', 'Position', [100 100 340 260]);
                axes('Units', 'pixels', 'Position', [10 10 320 240]);
                vidRes = get(app.vid, 'VideoResolution');
                nBands = get(app.vid, 'NumberOfBands');
                hImage = image(zeros(vidRes(2), vidRes(1), nBands));

                preview(app.vid, hImage);

                while (numel(sudoku) == 1 && app.isCamActive == 1)
                    data = getsnapshot(app.vid);
                    sudoku = getSudoku(data);
                    pause(0.5)
                end
                flushdata(app.vid);

                if sudoku ~= 0
                    if app.sudoku_check(sudoku) == 1
                        app.original_sudoku = sudoku;
                        app.current_sudoku = app.original_sudoku;
                        for i=1:9
                            for j=1:9
                                ind = sub2ind([9 9], j, i);
                                if sudoku(i,j) == 0
                                    eval("app.TextArea_" + num2str(ind) + ".FontWeight = 'normal'");
                                    eval("app.TextArea_" + num2str(ind) + ".Value = ''");
                                else
                                    eval("app.TextArea_" + num2str(ind) + ".FontWeight = 'bold'");
                                    eval("app.TextArea_" + num2str(ind) + ".Value = '" + num2str(app.current_sudoku(i,j)) + "'");
                                end
                            end
                        end
                        app.stopScanSudoku;
                    else
                        uialert(app.SudokuUIFigure,'El sudoku detectado no es válido','Error');
                    end
                end
            else
                app.stopScanSudoku;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SudokuUIFigure = uifigure('Visible', 'off');
            app.SudokuUIFigure.Name = 'Sudoku';
            app.EntradaporvozButton = uibutton(app.SudokuUIFigure, 'push');
            app.EntradaporvozButton.ButtonPushedFcn = createCallbackFcn(app, @EntradaporvozButtonPushed, true);
            app.EntradaporvozButton.Text = 'Entrada por voz';
            app.EntradaporimagenButton = uibutton(app.SudokuUIFigure, 'push');
            app.EntradaporimagenButton.ButtonPushedFcn = createCallbackFcn(app, @EntradaporimagenButtonPushed, true);
            app.EntradaporimagenButton.Text = 'Entrada por imagen';
            app.ReiniciarButton = uibutton(app.SudokuUIFigure, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.EscanearsudokuButton = uibutton(app.SudokuUIFigure, 'push');
            app.EscanearsudokuButton.ButtonPushedFcn = createCallbackFcn(app, @EscanearsudokuButtonPushed, true);
            app.EscanearsudokuButton.Text = 'Escanear sudoku';
            app.SUDOKULabel = uilabel(app.SudokuUIFigure);
            app.SUDOKULabel.Text = 'SUDOKU';
            app.TextArea_1 = uitextarea(app.SudokuUIFigure);
            app.TextArea_1.Editable = 'off';
            app.TextArea_2 = uitextarea(app.SudokuUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_3 = uitextarea(app.SudokuUIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_4 = uitextarea(app.SudokuUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_5 = uitextarea(app.SudokuUIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_6 = uitextarea(app.SudokuUIFigure);
            app.TextArea_6.Editable = 'off';
            app.TextArea_7 = uitextarea(app.SudokuUIFigure);
            app.TextArea_7.Editable = 'off';
            app.TextArea_8 = uitextarea(app.SudokuUIFigure);
            app.TextArea_8.Editable = 'off';
            app.TextArea_9 = uitextarea(app.SudokuUIFigure);
            app.TextArea_9.Editable = 'off';
            app.TextArea_10 = uitextarea(app.SudokuUIFigure);
            app.TextArea_10.Editable = 'off';
            app.TextArea_11 = uitextarea(app.SudokuUIFigure);
            app.TextArea_11.Editable = 'off';
            app.TextArea_12 = uitextarea(app.SudokuUIFigure);
            app.TextArea_12.Editable = 'off';
            app.TextArea_13 = uitextarea(app.SudokuUIFigure);
            app.TextArea_13.Editable = 'off';
            app.TextArea_14 = uitextarea(app.SudokuUIFigure);
            app.TextArea_14.Editable = 'off';
            app.TextArea_15 = uitextarea(app.SudokuUIFigure);
            app.TextArea_15.Editable = 'off';
            app.TextArea_16 = uitextarea(app.SudokuUIFigure);
            app.TextArea_16.Editable = 'off';
            app.TextArea_17 = uitextarea(app.SudokuUIFigure);
            app.TextArea_17.Editable = 'off';
            app.TextArea_18 = uitextarea(app.SudokuUIFigure);
            app.TextArea_18.Editable = 'off';
            app.TextArea_19 = uitextarea(app.SudokuUIFigure);
            app.TextArea_19.Editable = 'off';
            app.TextArea_20 = uitextarea(app.SudokuUIFigure);
            app.TextArea_20.Editable = 'off';
            app.TextArea_21 = uitextarea(app.SudokuUIFigure);
            app.TextArea_21.Editable = 'off';
            app.TextArea_22 = uitextarea(app.SudokuUIFigure);
            app.TextArea_22.Editable = 'off';
            app.TextArea_23 = uitextarea(app.SudokuUIFigure);
            app.TextArea_23.Editable = 'off';
            app.TextArea_24 = uitextarea(app.SudokuUIFigure);
            app.TextArea_24.Editable = 'off';
            app.TextArea_25 = uitextarea(app.SudokuUIFigure);
            app.TextArea_25.Editable = 'off';
            app.TextArea_26 = uitextarea(app.SudokuUIFigure);
            app.TextArea_26.Editable = 'off';
            app.TextArea_27 = uitextarea(app.SudokuUIFigure);
            app.TextArea_27.Editable = 'off';
            app.TextArea_28 = uitextarea(app.SudokuUIFigure);
            app.TextArea_28.Editable = 'off';
            app.TextArea_29 = uitextarea(app.SudokuUIFigure);
            app.TextArea_29.Editable = 'off';
            app.TextArea_30 = uitextarea(app.SudokuUIFigure);
            app.TextArea_30.Editable = 'off';
            app.TextArea_31 = uitextarea(app.SudokuUIFigure);
            app.TextArea_31.Editable = 'off';
            app.TextArea_32 = uitextarea(app.SudokuUIFigure);
            app.TextArea_32.Editable = 'off';
            app.TextArea_33 = uitextarea(app.SudokuUIFigure);
            app.TextArea_33.Editable = 'off';
            app.TextArea_34 = uitextarea(app.SudokuUIFigure);
            app.TextArea_34.Editable = 'off';
            app.TextArea_35 = uitextarea(app.SudokuUIFigure);
            app.TextArea_35.Editable = 'off';
            app.TextArea_36 = uitextarea(app.SudokuUIFigure);
            app.TextArea_36.Editable = 'off';
            app.TextArea_37 = uitextarea(app.SudokuUIFigure);
            app.TextArea_37.Editable = 'off';
            app.TextArea_38 = uitextarea(app.SudokuUIFigure);
            app.TextArea_38.Editable = 'off';
            app.TextArea_39 = uitextarea(app.SudokuUIFigure);
            app.TextArea_39.Editable = 'off';
            app.TextArea_40 = uitextarea(app.SudokuUIFigure);
            app.TextArea_40.Editable = 'off';
            app.TextArea_41 = uitextarea(app.SudokuUIFigure);
            app.TextArea_41.Editable = 'off';
            app.TextArea_42 = uitextarea(app.SudokuUIFigure);
            app.TextArea_42.Editable = 'off';
            app.TextArea_43 = uitextarea(app.SudokuUIFigure);
            app.TextArea_43.Editable = 'off';
            app.TextArea_44 = uitextarea(app.SudokuUIFigure);
            app.TextArea_44.Editable = 'off';
            app.TextArea_45 = uitextarea(app.SudokuUIFigure);
            app.TextArea_45.Editable = 'off';
            app.TextArea_46 = uitextarea(app.SudokuUIFigure);
            app.TextArea_46.Editable = 'off';
            app.TextArea_47 = uitextarea(app.SudokuUIFigure);
            app.TextArea_47.Editable = 'off';
            app.TextArea_48 = uitextarea(app.SudokuUIFigure);
            app.TextArea_48.Editable = 'off';
            app.TextArea_49 = uitextarea(app.SudokuUIFigure);
            app.TextArea_49.Editable = 'off';
            app.TextArea_50 = uitextarea(app.SudokuUIFigure);
            app.TextArea_50.Editable = 'off';
            app.TextArea_51 = uitextarea(app.SudokuUIFigure);
            app.TextArea_51.Editable = 'off';
            app.TextArea_52 = uitextarea(app.SudokuUIFigure);
            app.TextArea_52.Editable = 'off';
            app.TextArea_53 = uitextarea(app.SudokuUIFigure);
            app.TextArea_53.Editable = 'off';
            app.TextArea_54 = uitextarea(app.SudokuUIFigure);
            app.TextArea_54.Editable = 'off';
            app.TextArea_55 = uitextarea(app.SudokuUIFigure);
            app.TextArea_55.Editable = 'off';
            app.TextArea_56 = uitextarea(app.SudokuUIFigure);
            app.TextArea_56.Editable = 'off';
            app.TextArea_57 = uitextarea(app.SudokuUIFigure);
            app.TextArea_57.Editable = 'off';
            app.TextArea_58 = uitextarea(app.SudokuUIFigure);
            app.TextArea_58.Editable = 'off';
            app.TextArea_59 = uitextarea(app.SudokuUIFigure);
            app.TextArea_59.Editable = 'off';
            app.TextArea_60 = uitextarea(app.SudokuUIFigure);
            app.TextArea_60.Editable = 'off';
            app.TextArea_61 = uitextarea(app.SudokuUIFigure);
            app.TextArea_61.Editable = 'off';
            app.TextArea_62 = uitextarea(app.SudokuUIFigure);
            app.TextArea_62.Editable = 'off';
            app.TextArea_63 = uitextarea(app.SudokuUIFigure);
            app.TextArea_63.Editable = 'off';
            app.TextArea_64 = uitextarea(app.SudokuUIFigure);
            app.TextArea_64.Editable = 'off';
            app.TextArea_65 = uitextarea(app.SudokuUIFigure);
            app.TextArea_65.Editable = 'off';
            app.TextArea_66 = uitextarea(app.SudokuUIFigure);
            app.TextArea_66.Editable = 'off';
            app.TextArea_67 = uitextarea(app.SudokuUIFigure);
            app.TextArea_67.Editable = 'off';
            app.TextArea_68 = uitextarea(app.SudokuUIFigure);
            app.TextArea_68.Editable = 'off';
            app.TextArea_69 = uitextarea(app.SudokuUIFigure);
            app.TextArea_69.Editable = 'off';
            app.TextArea_70 = uitextarea(app.SudokuUIFigure);
            app.TextArea_70.Editable = 'off';
            app.TextArea_71 = uitextarea(app.SudokuUIFigure);
            app.TextArea_71.Editable = 'off';
            app.TextArea_72 = uitextarea(app.SudokuUIFigure);
            app.TextArea_72.Editable = 'off';
            app.TextArea_73 = uitextarea(app.SudokuUIFigure);
            app.TextArea_73.Editable = 'off';
            app.TextArea_74 = uitextarea(app.SudokuUIFigure);
            app.TextArea_74.Editable = 'off';
            app.TextArea_75 = uitextarea(app.SudokuUIFigure);
            app.TextArea_75.Editable = 'off';
            app.TextArea_76 = uitextarea(app.SudokuUIFigure);
            app.TextArea_76.Editable = 'off';
            app.TextArea_77 = uitextarea(app.SudokuUIFigure);
            app.TextArea_77.Editable = 'off';
            app.TextArea_78 = uitextarea(app.SudokuUIFigure);
            app.TextArea_78.Editable = 'off';
            app.TextArea_79 = uitextarea(app.SudokuUIFigure);
            app.TextArea_79.Editable = 'off';
            app.TextArea_80 = uitextarea(app.SudokuUIFigure);
            app.TextArea_80.Editable = 'off';
            app.TextArea_81 = uitextarea(app.SudokuUIFigure);
            app.TextArea_81.Editable = 'off';
            % Show the figure after all components are created
            app.SudokuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SudokuMacOs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SudokuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SudokuUIFigure)
        end
    end
end